classdef appMaquiaCDI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        TabGroup           matlab.ui.container.TabGroup
        tgFrVr             matlab.ui.container.Tab
        pResultados        matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        pDados             matlab.ui.container.Panel
        integralBox        matlab.ui.container.ButtonGroup
        Trapezios          matlab.ui.control.RadioButton
        Simpson            matlab.ui.control.RadioButton
        bAtualizar         matlab.ui.control.Button
        eIntN              matlab.ui.control.NumericEditField
        IntegralLabel      matlab.ui.control.Label
        Panel              matlab.ui.container.Panel
        bLabel             matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        hLabel             matlab.ui.control.Label
        eH                 matlab.ui.control.NumericEditField
        eA                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        fxLabel            matlab.ui.control.Label
        UIMostrador        matlab.ui.control.UIAxes
        tgFr2Vr            matlab.ui.container.Tab
        pResultados_2      matlab.ui.container.Panel
        UITable_2          matlab.ui.control.Table
        UIAxes_2           matlab.ui.control.UIAxes
        pDados_2           matlab.ui.container.Panel
        eHarmonica         matlab.ui.control.EditField
        ButtonGroup_2      matlab.ui.container.ButtonGroup
        Button3_2          matlab.ui.control.RadioButton
        Button2_2          matlab.ui.control.RadioButton
        Button_2           matlab.ui.control.RadioButton
        bAtualizar_2       matlab.ui.control.Button
        eR_2               matlab.ui.control.NumericEditField
        EditField3Label_2  matlab.ui.control.Label
        Panel_2            matlab.ui.container.Panel
        eB_3               matlab.ui.control.NumericEditField
        eH_3               matlab.ui.control.NumericEditField
        eA_3               matlab.ui.control.NumericEditField
        eB_2               matlab.ui.control.NumericEditField
        eH_2               matlab.ui.control.NumericEditField
        eA_2               matlab.ui.control.NumericEditField
        eF_2               matlab.ui.control.EditField
        fxyLabel           matlab.ui.control.Label
        UIMostrador_2      matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
            
        %Public functions used in project
            function YN = isHarmonica(~,f)
                syms z(x,y)
                if (diff(f,x,2)+diff(f,y,2)==0)
                    YN = 1;
                else
                    YN = 0;
                end
            end

            function [x,y,dydx] = NDerivadaDFP(~,f,a,b,h,y)
                x = a:h:b;
                n = length(x);
                if nargin == 5
                    y = f(x);
                end
                dydx = zeros(1,n);
                for i = 1:n-1
                    dydx(i) = (y(i+1)-y(i))/h;
                end
                dydx(n) = dydx(n-1);
            end
   

            function [x,y,dydx]= DFCentradas_3pontos(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                if nargin==4
                    y=f(x);
                end
                dydx=zeros(1,n);
                dydx(1)=(y(2)-y(1))/h;
                for i=2:n-1
                    dydx(i)=(y(i+1)-y(i-1))/(2*h);
                end
                dydx(n)=(y(n)-y(n-1))/h;
            end
            function [x,y,dydx]=DFRegressivas_2pontos(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                if nargin==4
                    y=f(x);
                end
                dydx=zeros(1,n);
                dydx(1)=(y(2)-y(1))/h;
                for i=2:n
                    dydx(i)=(y(i)-y(i-1))/h;
                end
            end

            function [x,y,dydx]=DFRegressivas_3pontos(~,f,a,b,h,y)
                x=a:h:b;
                n=length(x);
                if nargin==4
                    y=f(x);
                end
                dydx=zeros(1,n);
                dydx(1)=( (-3)*y(1) + 4*y(2) - y(3) )/(2*h);
                dydx(2)=( (-3)*y(2) + 4*y(3) - y(4) )/(2*h);
                for i=3:n
	                dydx(i)=( y(i-2) - 4*y(i-1) + 3*y(i) )/(2*h);
                end
            end
            
            function [x,y,dydx]=DFProgressivas_3pontos(~,f,a,b,h,y)
                    x=a:h:b;
                    n=length(x);
                    if nargin==4
                        y=f(x);
                    end
                    dydx=zeros(1,n);
                    for i=1:n-2
                        dydx(i)=( (-3)*y(i) + 4*y(i+1) - y(i+2) ) / (2*h);
                    end
                    dydx(n-1)=( y(n-3) - 4*y(n-2) + 3*y(n-1) )/(2*h);
                    dydx(n)=( y(n-2) - 4*y(n-1) + 3*y(n) )/(2*h);
            end

            function S = RSimpson(~,f,a,b,n)
                h = (b-a)/n;
                x = a:h:b;
                s = 0;
                for i = 2:n-1
                    if mod(i,2)==0
                        s = s+2*f(x(i));
                    else
                        s = s+4*f(x(i));
                    end
                end
                S = h/3*(f(a)+s+f(b));
            end

            function T = RTrapezios(~,f,a,b,n)
                
                h = (b-a)/n;
                s = 0;
                x=a;
                
                for i=1:n-1
	                x=x+h;
	                s = s+f(x);
                end
                
                T = h/2*((f(a) +2*s + f(b)));
            end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(x)
            strF = app.eF.Value;
            f = @(x) eval(vectorize(strF));
            a = app.eA.Value;
            h = app.eH.Value;
            b = app.eB.Value; 
               
            erroBA = MException("MATLAB:bAtualizar:BadF",...
                                    "Introduza um número real maior que a");
            erroMA = MException("MATLAB:bAtualizar:BadF",...
                                     "Introduza um numero maior ou igual que 0");

            escolhabg = app.integralBox.SelectedObject;
            escolhaM = find([app.Simpson, app.Trapezios]==escolhabg);

            try
                
                if ~(isscalar(b) && isreal(b) && b>a)
                    app.eB.BackgroundColor = [1,0,0]; % [r,g,b]
                    throw(erroBA)
                else
                    app.eB.BackgroundColor = [1,1,1]; % [r,g,b]
                end

                if ~(isscalar(a) && isreal(a) && a>=0)
                     app.eA.BackgroundColor = [1,0.5,1];
                     throw(erroMA)
                else
                    app.eA.BackgroundColor = [1,1,1];
                end             
                                                
                dydxSym = diff(f(x));
                g =@(x) eval(vectorize(char(dydxSym)));

                [x,y,dydxDFP] = NDerivadaDFP(app,f,a,b,h);
                %[~,~,dydxDFP3P] = DFProgressivas_3pontos(app,f,a,b,h);
                %[~,~,dydxDFR2P] = DFRegressivas_2pontos(app,f,a,b,h);
                %[~,~,dydxDFR3P] = DFRegressivas_3pontos(app,f,a,b,h);
                %[~,~,dydxDFC3P] = DFCentradas_3pontos(app,f,a,b,h);

                dydxExata = g(x);

                erroDFP = abs(dydxExata -dydxDFP);
                %erroDFP3P = abs(dydxExata -dydxDFP3P);
                %erroDFR2P = abs(dydxExata -dydxDFR2P);
                %erroDFR3P = abs(dydxExata -dydxDFR3P);
                %erroDFC3P = abs(dydxExata -dydxDFC3P);

                tabela = array2table([x.',y.',dydxExata.', dydxDFP.',erroDFP.']);
                nomeColunas = {'x','y','dxdyExata','dxdyDFP','ErroDFP','dydxDFP','erroDFP'};
                app.UITable.Data = tabela;
                app.UITable.ColumnName = nomeColunas;

                
                plot(app.UIAxes,x,y,"-r+")
                hold(app.UIAxes,"on")
                plot(app.UIAxes,x, dydxExata, "-bo")
                plot(app.UIAxes,x,dydxDFP,"-g*")
                hold(app.UIAxes,"off")
                formaLatex =['$','\frac{dy}{dx}=',latex(dydxSym),'$'];
                cla(app.UIMostrador)
                %app.UIMostrador.Toolbar.Visible = 'off';
                text(app.UIMostrador,0.1,0.5,formaLatex,"Interpreter","latex","FontSize",18);
      
                n = (b-a)/h;

                switch escolhaM
                case 1
                     S = RSimpson(app,f,a,b,n);
                     app.eIntN.Value = S;
                     area(app.UIAxes,x,y,"FaceColor","g");        
                case 2
                    T =  RTrapezios(app,f,a,b,n);
                    app.eIntN.Value = T;
                    area(app.UIAxes,x,y,"FaceColor","r");   
                end

            catch ME
                errordlg(ME.message,"ERRO","modal")
            end


        end

        % Button pushed function: bAtualizar_2
        function bAtualizar_2ButtonPushed(app, event)
            strFxy = app.eF_2.Value;
            f =@(x,y) eval(vectorize(strFxy));
            a = app.eA_2.Value; h1 = app.eH_2.Value; b = app.eB_2.Value;
            c = app.eA_3.Value; h2 = app.eH_3.Value; d = app.eB_3.Value;
            [x,y] = meshgrid(a:h1:b, c:h2:d);
            z = f(x,y);
            %surf(app.UIAxes_2, x,y,z)
            mesh(app.UIAxes_2, x,y,z)
            
            if isHarmonica(app,f)
                strHarmonica = "f é harmónica";
                corHarmonica = "g";
            else
                strHarmonica = "f não é harmónica";
                corHarmonica = "r";                               
            end
            app.eHarmonica.Value = strHarmonica;
            app.eHarmonica.FontColor = corHarmonica;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.tgFrVr = uitab(app.TabGroup);
            app.tgFrVr.Title = 'Função real de variável real y = f(x)';
            app.pDados = uipanel(app.tgFrVr);
            app.UIMostrador = uiaxes(app.pDados);
            app.UIMostrador.XTick = [];
            app.UIMostrador.YTick = [];
            app.Panel = uipanel(app.pDados);
            app.fxLabel = uilabel(app.Panel);
            app.fxLabel.Text = 'f(x) = ';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Value = 'sin(x)';
            app.aLabel = uilabel(app.Panel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eH = uieditfield(app.Panel, 'numeric');
            app.eH.Value = 0.1;
            app.hLabel = uilabel(app.Panel);
            app.hLabel.Text = 'h';
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 6.28318530717959;
            app.bLabel = uilabel(app.Panel);
            app.bLabel.Text = 'b';
            app.IntegralLabel = uilabel(app.pDados);
            app.IntegralLabel.Text = 'Integral  ';
            app.eIntN = uieditfield(app.pDados, 'numeric');
            app.bAtualizar = uibutton(app.pDados, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Icon = 'atualizar.png';
            app.bAtualizar.Text = 'Atualizar';
            app.integralBox = uibuttongroup(app.pDados);
            app.integralBox.Title = 'Métodos para integrais';
            app.Simpson = uiradiobutton(app.integralBox);
            app.Simpson.Text = 'Regra de Simpson';
            app.Simpson.Value = true;
            app.Trapezios = uiradiobutton(app.integralBox);
            app.Trapezios.Text = 'Regra dos Trapézios';
            app.pResultados = uipanel(app.tgFrVr);
            app.UIAxes = uiaxes(app.pResultados);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.pResultados);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.tgFr2Vr = uitab(app.TabGroup);
            app.tgFr2Vr.Title = 'Função real de 2 variáveis reais z = f(x,y)';
            app.pDados_2 = uipanel(app.tgFr2Vr);
            app.UIMostrador_2 = uiaxes(app.pDados_2);
            app.UIMostrador_2.XTick = [];
            app.UIMostrador_2.YTick = [];
            app.Panel_2 = uipanel(app.pDados_2);
            app.fxyLabel = uilabel(app.Panel_2);
            app.fxyLabel.Text = 'f(x,y) = ';
            app.eF_2 = uieditfield(app.Panel_2, 'text');
            app.eF_2.Value = 'x^2+y^2';
            app.eA_2 = uieditfield(app.Panel_2, 'numeric');
            app.eA_2.Value = -2;
            app.eH_2 = uieditfield(app.Panel_2, 'numeric');
            app.eH_2.Value = 0.1;
            app.eB_2 = uieditfield(app.Panel_2, 'numeric');
            app.eB_2.Value = 2;
            app.eA_3 = uieditfield(app.Panel_2, 'numeric');
            app.eA_3.Value = -2;
            app.eH_3 = uieditfield(app.Panel_2, 'numeric');
            app.eH_3.Value = 0.1;
            app.eB_3 = uieditfield(app.Panel_2, 'numeric');
            app.eB_3.Value = 2;
            app.EditField3Label_2 = uilabel(app.pDados_2);
            app.EditField3Label_2.Text = 'Edit Field3';
            app.eR_2 = uieditfield(app.pDados_2, 'numeric');
            app.bAtualizar_2 = uibutton(app.pDados_2, 'push');
            app.bAtualizar_2.ButtonPushedFcn = createCallbackFcn(app, @bAtualizar_2ButtonPushed, true);
            app.bAtualizar_2.Text = 'Atializar';
            app.ButtonGroup_2 = uibuttongroup(app.pDados_2);
            app.ButtonGroup_2.Title = 'Button Group';
            app.Button_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button_2.Text = 'Button';
            app.Button_2.Value = true;
            app.Button2_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button2_2.Text = 'Button2';
            app.Button3_2 = uiradiobutton(app.ButtonGroup_2);
            app.Button3_2.Text = 'Button3';
            app.eHarmonica = uieditfield(app.pDados_2, 'text');
            app.pResultados_2 = uipanel(app.tgFr2Vr);
            app.UIAxes_2 = uiaxes(app.pResultados_2);
            title(app.UIAxes_2, 'Title')
            xlabel(app.UIAxes_2, 'X')
            ylabel(app.UIAxes_2, 'Y')
            zlabel(app.UIAxes_2, 'Z')
            app.UITable_2 = uitable(app.pResultados_2);
            app.UITable_2.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable_2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquiaCDI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end