classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        GridLayout             matlab.ui.container.GridLayout
        LeftPanel              matlab.ui.container.Panel
        ProblemasButtonGroup   matlab.ui.container.ButtonGroup
        CircuitoEltricoButton  matlab.ui.control.ToggleButton
        PnduloButton           matlab.ui.control.ToggleButton
        MolaMassaButton        matlab.ui.control.ToggleButton
        ReiniciarButton        matlab.ui.control.Button
        bAtualizar             matlab.ui.control.Button
        bgMetodos              matlab.ui.container.ButtonGroup
        rbTodos                matlab.ui.control.RadioButton
        rbRK4                  matlab.ui.control.RadioButton
        rbRK2                  matlab.ui.control.RadioButton
        rbEulerM               matlab.ui.control.RadioButton
        rbEuler                matlab.ui.control.RadioButton
        Panel                  matlab.ui.container.Panel
        v0Label                matlab.ui.control.Label
        ev0                    matlab.ui.control.NumericEditField
        eG                     matlab.ui.control.TextArea
        gtuvLabel              matlab.ui.control.Label
        u0Label                matlab.ui.control.Label
        eu0                    matlab.ui.control.NumericEditField
        nLabel                 matlab.ui.control.Label
        eN                     matlab.ui.control.NumericEditField
        bLabel                 matlab.ui.control.Label
        eB                     matlab.ui.control.NumericEditField
        eA                     matlab.ui.control.NumericEditField
        aLabel                 matlab.ui.control.Label
        eF                     matlab.ui.control.EditField
        ftuvLabel              matlab.ui.control.Label
        RightPanel             matlab.ui.container.Panel
        UITable                matlab.ui.control.Table
        UIAxes                 matlab.ui.control.UIAxes
        OpesMenu               matlab.ui.container.Menu
        GuardarComoMenu        matlab.ui.container.Menu
        SairMenu               matlab.ui.container.Menu
        SobreMenu              matlab.ui.container.Menu
        AlunosMenu             matlab.ui.container.Menu
        RelatrioMenu           matlab.ui.container.Menu
        ContextMenu            matlab.ui.container.ContextMenu
        Menu                   matlab.ui.container.Menu
        Menu2                  matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% 
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        syms  u(t) v(t)
        strF = app.eF.Value;
        strG = app.eG.Value;
        f = @(t,u,v) eval(vectorize(strF));
        g = @(t,u,v) eval(vectorize(strG));
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        u0 = app.eu0.Value;
        v0 = app.ev0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        escolhaM = find([app.rbEuler, app.rbEulerM, app.rbRK2,...
                         app.rbRK4, app.rbTodos]==escolhabg);
        erroFuncao = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t,u e v");
        erroBA = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza um número real maior que a");
         erroMA = MException("MATLAB:bAtualizar:BadF",...
                                 "Introduza um numero maior ou igual que 0");
         erroMN = MException("MATLAB:bAtualizar:BadF",...
                                 "Introduza um numero maior que 0");


        try
            
            try
                 z = g(t,u,v);
                    app.eG.BackgroundColor = "w";
            catch
                app.eG.BackgroundColor = [1,0,0]; %[r,g,b]                  
                    throw(erroFuncao)
            end
            
            if ~(isscalar(b) && isreal(b) && b>a)
                app.eB.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroBA)
            else
                app.eB.BackgroundColor = [1,1,1]; % [r,g,b]
            end
            if ~(isscalar(a) && isreal(a) && a>=0)
                 app.eA.BackgroundColor = [1,0.5,1];
                 throw(erroMA)
            else
                app.eA.BackgroundColor = [1,1,1];
            end
            if ~(isscalar(n) && isreal(n) && n>0)
                 app.eA.BackgroundColor = [1,0.5,1];
                 throw(erroMN)
            else
                app.eA.BackgroundColor = [1,1,1];
            end
 

                eqns = [diff(u,t)==v,diff(v,t)==-16*u];
                ci = [u(0)==u0,v(0)==v0];
                [sExataU,sExataV] = dsolve(eqns,ci);
                
                h = @(t) eval(vectorize(char(sExataU)));
                
                
                [t,uEuler,~] = NEulerSED(f,g,a,b,n,u0,v0);
                [t,uEulerM,~] = NEM(f,g,a,b,n,u0,v0);
                [~,uRK2,~] = NRK2(f,g,a,b,n,u0,v0);
                [~,uRK4,~] = NRK4(f,g,a,b,n,u0,v0);

                uExata = h(t);
                
                erroEuler = abs(uExata-uEuler);
                erroEulerM = abs(uExata-uEulerM);
                erroRK2 = abs(uExata-uRK2);
                erroRK4 = abs(uExata-uRK4);

                plot(app.UIAxes,t,uExata,"-r*")
                hold(app.UIAxes,"on")  

            switch escolhaM
                case 1
                    plot(app.UIAxes,t,uEuler,"-g*")   
                    legenda = {'Exata','Euler'};
                    tabela = [t.',uExata.',uEuler.',erroEuler.']; 
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    plot(app.UIAxes,t,uEulerM,"-g*")   
                    legenda = {'Exata','EulerM'};
                    tabela = [t.',uExata.',uEulerM.',erroEulerM.']; 
                    nomeColunas = {'t','Exata','EulerM','erroEulerM'};
                case 3
                    plot(app.UIAxes,t,uRK2,"-bo")   
                    legenda = {'Exata','RK2'};
                    tabela = [t.',uExata.',uRK2.',erroRK2.']; 
                    nomeColunas = {'t','Exata','RK2','erroRK2'};                    
                case 4
                    plot(app.UIAxes,t,uRK4,"-bo")   
                    legenda = {'Exata','RK4'};
                    tabela = [t.',uExata.',uRK4.',erroRK4.']; 
                    nomeColunas = {'t','Exata','RK4','erroRK4'};     
                case 5
                   plot(app.UIAxes,t,uEuler,"-y*")   
                   plot(app.UIAxes,t,uEulerM,"-gd")
                   plot(app.UIAxes,t,uRK2,"-bo")  
                   plot(app.UIAxes,t,uRK4,"-b*")

                   legenda = {'Exata','Euler','EulerM','RK2','RK4'};                  
                   tabela = [t.',uExata.',uEuler.',uEulerM.',uRK2.',...
                             uRK4.',...
                             erroEuler.',erroEulerM.',erroRK2.',...
                             erroRK4.']; 
                   nomeColunas = {'t','Exata','Euler','EulerM','RK2',...
                                  'RK4','erroEuler',...
                                  'erroEulerM','erroRK2','erroRK4'}; 
            end
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;

            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            

        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
            SN = questdlg("Quer sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure)
            end
        end

        % Menu selected function: AlunosMenu
        function AlunosMenuSelected(app, event)
            app1
        end

        % Menu selected function: RelatrioMenu
        function RelatrioMenuSelected(app, event)
            open("LuísTravassos_2021136600_PedroJorge_2021142041.pdf")
        end

        % Menu selected function: GuardarComoMenu
        function GuardarComoMenuSelected(app, event)

            SN = questdlg("Deseja guardar?","Guardar","Sim","Não","Sim");
            if strcmp(SN,"Sim")
             filter = {'*.jpg';'*.png';'*.pdf'};
             [filename,filepath] = uiputfile(filter);
                if ischar(filename)
                    exportapp(app.UIFigure,[filepath filename]);
                end
            end
        end

        % Button pushed function: ReiniciarButton
        function ReiniciarButtonPushed(app, event)
        app.UIFigure.Visible = 'off';
        appMaquinaPVI();
        close(app.UIFigure)
        end

        % Callback function
        function MolamassacomamortecimentoMenuSelected(app, event)
                        
        end

        % Callback function: ProblemasButtonGroup, eG
        function ProblemasButtonGroupSelectionChanged(app, event)
            selectedButton = app.ProblemasButtonGroup.SelectedObject;
            
            switch selectedButton
                 
                case app.MolaMassaButton
                    app.eG.Editable = 'off';
                    strG = "-10*v-25*u";
                    a = 0; b = 15; n = 100;
                    u0 = pi/2; v0 = 0;
                case app.PnduloButton
                    app.eG.Editable = 'off';
                    strG = "-sin(u)-0.3*v";
                    a = 0; b = 15; n = 50;
                    u0 = pi/2; v0 = 0;
                
                case app.CircuitoEltricoButton
                    app.eG.Editable = 'off';
                    strG = "-(4/3)*v-(16/3)*u";
                    a = 0; b = 10; n = 100;
                    u0 = 9; v0 = 0;

            end
            app.eG.Value = strG;
            app.eA.Value = a; app.eB.Value = b; app.eN.Value=n;
            app.eu0.Value = u0; app.ev0.Value = v0;
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {611, 611};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {242, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.Panel = uipanel(app.LeftPanel);
            app.ftuvLabel = uilabel(app.Panel);
            app.ftuvLabel.Text = 'f(t,u,v) = ';
            app.eF = uieditfield(app.Panel, 'text');
            app.eF.Editable = 'off';
            app.eF.Value = 'v';
            app.aLabel = uilabel(app.Panel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.Panel, 'numeric');
            app.eB = uieditfield(app.Panel, 'numeric');
            app.eB.Value = 15;
            app.bLabel = uilabel(app.Panel);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.Panel, 'numeric');
            app.eN.Value = 100;
            app.nLabel = uilabel(app.Panel);
            app.nLabel.Text = 'n';
            app.eu0 = uieditfield(app.Panel, 'numeric');
            app.eu0.Value = 1.5707;
            app.u0Label = uilabel(app.Panel);
            app.u0Label.Text = 'u0';
            app.gtuvLabel = uilabel(app.Panel);
            app.gtuvLabel.Text = 'g(t,u,v) =';
            app.eG = uitextarea(app.Panel);
            app.eG.ValueChangedFcn = createCallbackFcn(app, @ProblemasButtonGroupSelectionChanged, true);
            app.eG.Value = {'-10*v-25*u'};
            app.ev0 = uieditfield(app.Panel, 'numeric');
            app.v0Label = uilabel(app.Panel);
            app.v0Label.Text = 'v0';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Icon = 'atualizar.png';
            app.bAtualizar.Text = 'Atualizar';
            app.ReiniciarButton = uibutton(app.LeftPanel, 'push');
            app.ReiniciarButton.ButtonPushedFcn = createCallbackFcn(app, @ReiniciarButtonPushed, true);
            app.ReiniciarButton.Text = 'Reiniciar';
            app.ProblemasButtonGroup = uibuttongroup(app.LeftPanel);
            app.ProblemasButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @ProblemasButtonGroupSelectionChanged, true);
            app.ProblemasButtonGroup.Title = 'Problemas';
            app.MolaMassaButton = uitogglebutton(app.ProblemasButtonGroup);
            app.MolaMassaButton.Text = '                Mola-Massa            ';
            app.MolaMassaButton.Value = true;
            app.PnduloButton = uitogglebutton(app.ProblemasButtonGroup);
            app.PnduloButton.Text = '                 Pêndulo                  ';
            app.CircuitoEltricoButton = uitogglebutton(app.ProblemasButtonGroup);
            app.CircuitoEltricoButton.Text = '             Circuito Elétrico          ';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.OpesMenu = uimenu(app.UIFigure);
            app.OpesMenu.Text = 'Opções';
            app.GuardarComoMenu = uimenu(app.OpesMenu);
            app.GuardarComoMenu.MenuSelectedFcn = createCallbackFcn(app, @GuardarComoMenuSelected, true);
            app.GuardarComoMenu.Text = 'Guardar Como';
            app.SairMenu = uimenu(app.OpesMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.SobreMenu = uimenu(app.UIFigure);
            app.SobreMenu.Text = 'Sobre';
            app.AlunosMenu = uimenu(app.SobreMenu);
            app.AlunosMenu.MenuSelectedFcn = createCallbackFcn(app, @AlunosMenuSelected, true);
            app.AlunosMenu.Text = 'Alunos';
            app.RelatrioMenu = uimenu(app.SobreMenu);
            app.RelatrioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatrioMenuSelected, true);
            app.RelatrioMenu.Text = 'Relatório';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.Menu = uimenu(app.ContextMenu);
            app.Menu.Text = 'Menu';
            app.Menu2 = uimenu(app.ContextMenu);
            app.Menu2.Text = 'Menu2';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end