classdef appMaquinaEDO < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure           matlab.ui.Figure
        GridLayout         matlab.ui.container.GridLayout
        LeftPanel          matlab.ui.container.Panel
        bAtualizar         matlab.ui.control.Button
        bgMetodos          matlab.ui.container.ButtonGroup
        rbTodos            matlab.ui.control.RadioButton
        rbMP               matlab.ui.control.RadioButton
        rbODE45            matlab.ui.control.RadioButton
        rbRK4              matlab.ui.control.RadioButton
        rbRK2              matlab.ui.control.RadioButton
        rbEulerM           matlab.ui.control.RadioButton
        rbEuler            matlab.ui.control.RadioButton
        ValoresPanel       matlab.ui.container.Panel
        y0Label            matlab.ui.control.Label
        eY0                matlab.ui.control.NumericEditField
        nLabel             matlab.ui.control.Label
        eN                 matlab.ui.control.NumericEditField
        bLabel             matlab.ui.control.Label
        eB                 matlab.ui.control.NumericEditField
        eA                 matlab.ui.control.NumericEditField
        aLabel             matlab.ui.control.Label
        eF                 matlab.ui.control.EditField
        ftyEditFieldLabel  matlab.ui.control.Label
        RightPanel         matlab.ui.container.Panel
        UITable            matlab.ui.control.Table
        UIAxes             matlab.ui.control.UIAxes
        DefiniesMenu       matlab.ui.container.Menu
        SairMenu           matlab.ui.container.Menu
        RelatorioMenu      matlab.ui.container.Menu
        DadosMenu          matlab.ui.container.Menu
        AutorMenu          matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app, [])
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
        format shortg; %Formato para diminuir a sintaxe de valores com notação ciêntifica
         syms y(t)
        strF = app.eF.Value;
        f = @(t,y) eval(vectorize(strF));
        a = app.eA.Value;
        b = app.eB.Value;        
        n = app.eN.Value;
        y0 = app.eY0.Value;
        
        escolhabg = app.bgMetodos.SelectedObject;
        opcao = find([app.rbEuler, app.rbEulerM, app.rbRK2,...
                         app.rbRK4, app.rbODE45, app.rbMP,...
                         app.rbTodos]==escolhabg);
        erroFuncao = MException("MATLAB:bAtualizar:BadF",...
                                "Introduza uma função em t e y");
        
        % outras 
        
        
        try
            
            try
                z = f(t,y);
                app.eF.BackgroundColor = "w";  
            catch
                app.eF.BackgroundColor = [1,0,0]; % [r,g,b]
                throw(erroFuncao)          
            end

            if ~(isreal(b) && b>a)
                app.eB.BackgroundColor = [1,0,0];
            end    

            %Calculo dos métodos
            yEuler = NEuler(f,a,b,n,y0);
            yEM = NEM(f,a,b,n,y0);
            yMP = MP(f,a,b,n,y0);
            yODE45 = ODE45(f,a,b,n,y0);
            yRK2 = NRK2(f,a,b,n,y0);
            yRK4 = NRK4(f,a,b,n,y0);
            
            %Calculo da solução aproximada
            sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
            
            g =@(t) eval(vectorize(char(sExata)));
            
            h = (b-a)/n;
            t = a:h:b;
            yExata = g(t);

            %Calculos dos erros
            erroEuler = abs(yExata-yEuler);
            erroEM = abs(yExata-yEM);%Erro do euler melhorado
            erroMP = abs(yExata-yMP);%Erro do euler melhorado
            erroRK2 = abs(yExata-yRK2);
            erroRK4 = abs(yExata-yRK4);
            erroODE45  = abs(yExata-yODE45);
            
            plot(app.UIAxes,t,yExata,"-go")
            hold(app.UIAxes,"on")            

            switch opcao
                case 1
                    plot(app.UIAxes,t,yEuler)
                    legenda = {'Exata','Euler'};
                    tabela = [t.',yExata.',yEuler.',erroEuler.']; 
                    nomeColunas = {'t','Exata','Euler','erroEuler'};
                case 2
                    plot(app.UIAxes,t,yEM)
                    legenda = {'Exata','Euler Melhorado'};
                    tabela = [t.',yExata.',yEM.',erroEM.']; 
                    nomeColunas = {'t','Exata','Euler','erroEM'};
                case 3
                    plot(app.UIAxes,t,yRK2,"-bo")   
                    legenda = {'Exata','RK2'};
                    tabela = [t.',yExata.',yRK2.',erroRK2.']; 
                    nomeColunas = {'t','Exata','RK2','erroRK2'};                    
                case 4
                    plot(app.UIAxes,t,yRK4)   
                    legenda = {'Exata','RK4'};
                    tabela = [t.',yExata.',yRK4.',erroRK4.']; 
                    nomeColunas = {'t','Exata','RK4','erroRK4'};                   
                case 5
                    plot(app.UIAxes,t,yODE45)   
                    legenda = {'Exata','ODE45'};    
                    tabela = [t.',yExata.',yODE45.',erroODE45.']; 
                    nomeColunas = {'t','Exata','ODE45','erroODE45'};     
                case 6
                    plot(app.UIAxes,t,yMP)   
                    legenda = {'Exata','MP'};    
                    tabela = [t.',yExata.',yMP.',erroMP.']; 
                    nomeColunas = {'t','Exata','MP','erroMP'}; 
                case 7
                   plot(app.UIAxes,t,yEuler,"-g*")   
                   plot(app.UIAxes,t,yRK2,"-bo")   
                   plot(app.UIAxes,t,yRK4)   
                   plot(app.UIAxes,t,yODE45)
                   plot(app.UIAxes,t,yMP)
                   plot(app.UIAxes,t,yEM)
                   legenda = {'Exata','Euler','RK2','RK4','ODE45','MP','EM'};                  
                   tabela = [t.',yExata.',yEuler.',yRK2.',yRK4.',yODE45.',yMP.',yEM.',erroEuler.',erroRK2.',erroRK4.'...
                       ,erroODE45.',erroMP.',erroEM.']; 
                   nomeColunas = {'t','Exata','Euler','RK2',...
                                  'RK4','ODE45','MP','Euler Melhorado','erroEuler','erroRK2','erroRK4','erroODE45','erroMP','Erro Euler Melhorado'}; 
            end
            
            legend(app.UIAxes, legenda) 
            hold(app.UIAxes,"off")
            grid(app.UIAxes,"on")
            
            T = array2table(tabela);
            app.UITable.Data = T; 
            app.UITable.ColumnName = nomeColunas;
                
        catch ME
            errordlg(ME.message,"ERRO","modal")               
        end


        end

        % Menu selected function: SairMenu
        function SairMenuSelected(app, event)
           SN = questdlg("Quer Sair?","SAIR","Sim","Não","Sim");
            if strcmp(SN,"Sim")
                delete(app.UIFigure);
            end
        end

        % Callback function: AutorMenu, eB
        function AutorMenuSelected(app, event)
            app1;
        end

        % Menu selected function: RelatorioMenu
        function RelatorioMenuSelected(app, event)
            open("Relatorio_TP1_AM2.pdf")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {354, 354};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {232, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.ValoresPanel = uipanel(app.LeftPanel);
            app.ValoresPanel.Title = 'Valores';
            app.ftyEditFieldLabel = uilabel(app.ValoresPanel);
            app.ftyEditFieldLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.ValoresPanel, 'text');
            app.eF.Placeholder = 'introduza uma função';
            app.eF.Value = 't+y';
            app.aLabel = uilabel(app.ValoresPanel);
            app.aLabel.Text = 'a';
            app.eA = uieditfield(app.ValoresPanel, 'numeric');
            app.eB = uieditfield(app.ValoresPanel, 'numeric');
            app.eB.ValueChangedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.eB.Value = 1.5;
            app.bLabel = uilabel(app.ValoresPanel);
            app.bLabel.Text = 'b';
            app.eN = uieditfield(app.ValoresPanel, 'numeric');
            app.eN.Value = 10;
            app.nLabel = uilabel(app.ValoresPanel);
            app.nLabel.Text = 'n';
            app.eY0 = uieditfield(app.ValoresPanel, 'numeric');
            app.eY0.Value = 2;
            app.y0Label = uilabel(app.ValoresPanel);
            app.y0Label.Text = 'y0';
            app.bgMetodos = uibuttongroup(app.LeftPanel);
            app.bgMetodos.Title = 'Métodos Numéricos';
            app.rbEuler = uiradiobutton(app.bgMetodos);
            app.rbEuler.Text = 'Euler';
            app.rbEuler.Value = true;
            app.rbEulerM = uiradiobutton(app.bgMetodos);
            app.rbEulerM.Text = 'Euler Melhorado';
            app.rbRK2 = uiradiobutton(app.bgMetodos);
            app.rbRK2.Text = 'RK2';
            app.rbRK4 = uiradiobutton(app.bgMetodos);
            app.rbRK4.Text = 'RK4';
            app.rbODE45 = uiradiobutton(app.bgMetodos);
            app.rbODE45.Text = 'ODE45';
            app.rbMP = uiradiobutton(app.bgMetodos);
            app.rbMP.Text = 'MP';
            app.rbTodos = uiradiobutton(app.bgMetodos);
            app.rbTodos.Text = 'Todos';
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Icon = 'atualizar.png';
            app.bAtualizar.Text = 'Atualizar';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Representação Gráfica dos Métodos')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UITable = uitable(app.RightPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.DefiniesMenu = uimenu(app.UIFigure);
            app.DefiniesMenu.Text = 'Definições';
            app.SairMenu = uimenu(app.DefiniesMenu);
            app.SairMenu.MenuSelectedFcn = createCallbackFcn(app, @SairMenuSelected, true);
            app.SairMenu.Text = 'Sair';
            app.RelatorioMenu = uimenu(app.DefiniesMenu);
            app.RelatorioMenu.MenuSelectedFcn = createCallbackFcn(app, @RelatorioMenuSelected, true);
            app.RelatorioMenu.Text = 'Relatorio';
            app.DadosMenu = uimenu(app.UIFigure);
            app.DadosMenu.Text = 'Dados';
            app.AutorMenu = uimenu(app.DadosMenu);
            app.AutorMenu.MenuSelectedFcn = createCallbackFcn(app, @AutorMenuSelected, true);
            app.AutorMenu.Text = 'Autor';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaEDO

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end